classdef app < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        deltaOmegaLabl               matlab.ui.control.Label
        deltaOmega                   matlab.ui.control.NumericEditField
        psatLabel_3                  matlab.ui.control.Label
        psatTxt_2                    matlab.ui.control.NumericEditField
        omega_textsalidaLabel        matlab.ui.control.Label
        omegaTxt_2                   matlab.ui.control.NumericEditField
        deltaTaTxt                   matlab.ui.control.NumericEditField
        deltaTwTxt                   matlab.ui.control.NumericEditField
        deltaTw                      matlab.ui.control.Label
        deltaTa                      matlab.ui.control.Label
        UnidadesdetemperaturaSwitch  matlab.ui.control.Switch
        UnidadesdetemperaturaSwitchLabel  matlab.ui.control.Label
        psatLabel_2                  matlab.ui.control.Label
        p0                           matlab.ui.control.NumericEditField
        Twet                         matlab.ui.control.NumericEditField
        Tdew                         matlab.ui.control.NumericEditField
        mwev                         matlab.ui.control.NumericEditField
        ma                           matlab.ui.control.NumericEditField
        mwin                         matlab.ui.control.NumericEditField
        deltaP                       matlab.ui.control.NumericEditField
        phi1                         matlab.ui.control.NumericEditField
        Twout                        matlab.ui.control.NumericEditField
        Taout                        matlab.ui.control.NumericEditField
        Ttank                        matlab.ui.control.NumericEditField
        Twin                         matlab.ui.control.NumericEditField
        Tain                         matlab.ui.control.NumericEditField
        phi0                         matlab.ui.control.NumericEditField
        omegaTxt                     matlab.ui.control.NumericEditField
        psatTxt                      matlab.ui.control.NumericEditField
        omega_textambLabel           matlab.ui.control.Label
        psatLabel                    matlab.ui.control.Label
        Run                          matlab.ui.control.Button
        Image                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Run
        function RunPushed(comp, event)
            %Datos
           T_ain = get(comp.Tain, 'Value');
           phia0 = get(comp.phi0, 'Value');
           T_tank = get(comp.Ttank, 'Value');
           T_win = get(comp.Twin, 'Value');
           T_aout = get(comp.Taout, 'Value');
           T_wout = get(comp.Twout, 'Value');
           phia1 = get(comp.phi1, 'Value');
           deltaP = get(comp.deltaP, 'Value');
           p_Amb = get(comp.p0, 'Value');
           m_win = get(comp.mwin, 'Value');

           %Crea objeto + resuleve el problema
           problema = calculadora(p_Amb, T_ain);
           ps0 = problema.psat(); %ps entrada
           ps1 = problema.psat(T_aout); %ps salida
           omega0 = problema.omega(ps0, phia0); %humedad abs entrada
           omega1 = problema.omega(ps1, phia1, p_Amb + deltaP); %humedad abs salida
           T_dew = problema.tdew(phia0);% T rocio
           ma = problema.ma(T_ain, T_aout, T_win, T_wout, omega0, omega1, m_win);
           mwev = problema.mwevap(omega0, omega1, ma);

           %Escribir datos
           set(comp.psatTxt,'Value', ps0); %Presion saturacion ambiente
           set(comp.omegaTxt, 'Value', omega0); %Humedad absoluta ambiente
           set(comp.psatTxt_2,'Value', ps1); %Presion saturacion salida
           set(comp.omegaTxt_2, 'Value', omega1); %Humedad absoluta salida
           set(comp.Tdew, 'Value', T_dew); % Temperatura rocio
           set(comp.ma, 'Value', ma);
           set(comp.mwev, 'Value', mwev);
           set(comp.deltaTaTxt, 'Value', T_aout - T_ain); %Diferencia Temperatura aire
           set(comp.deltaTwTxt, 'Value', T_wout - T_win); %Diferencia Temperatura agua
           set(comp.deltaOmega, 'Value', omega1 - omega0); %Diferencia humedades absolutas
        end

        % Value changed function: UnidadesdetemperaturaSwitch
        function UnidadesdetemperaturaSwitchValueChanged(comp, event)
            uds = comp.UnidadesdetemperaturaSwitch.Value;
            Tain = get(comp.Tain, 'Value');
            Twout = get(comp.Twout, 'Value');
            Ttank = get(comp.Ttank, 'Value');
            Twin = get(comp.Twin, 'Value');
            Taout = get(comp.Taout, 'Value');
            Tdew = get(comp.Tdew, 'Value');
            Twet = get(comp.Twet, 'Value');
            
            if uds=="c"
                set(comp.Tain, 'Value', Tain-273)
                set(comp.Twout, 'Value', Twout-273)
                set(comp.Ttank, 'Value', Ttank-273)
                set(comp.Twin, 'Value', Twin-273)
                set(comp.Taout, 'Value', Taout-273)
                set(comp.Tdew, 'Value', Tdew-273)
                set(comp.Twet, 'Value', Twet-273)
                set(comp.Run, 'Text', 'Cambia a Kelvin')
                set(comp.Run, 'Enable', false)
            else
                set(comp.Tain, 'Value', Tain+273)
                set(comp.Twout, 'Value', Twout+273)
                set(comp.Ttank, 'Value', Ttank+273)
                set(comp.Twin, 'Value', Twin+273)
                set(comp.Taout, 'Value', Taout+273)
                set(comp.Tdew, 'Value', Tdew+273)
                set(comp.Twet, 'Value', Twet+273)
                set(comp.Run, 'Text', 'Ejecutar')
                set(comp.Run, 'Enable', true)
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 830 601];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.Image = uiimage(comp);
            comp.Image.ImageSource = fullfile(pathToMLAPP, 'Screenshot from 2023-10-30 16-54-48.png');
            comp.Run = uibutton(comp, 'push');
            comp.Run.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunPushed, true);
            comp.Run.Text = 'Ejecutar';
            comp.psatLabel = uilabel(comp);
            comp.psatLabel.Interpreter = 'latex';
            comp.psatLabel.Text = '$p_\text{sat, amb}$';
            comp.omega_textambLabel = uilabel(comp);
            comp.omega_textambLabel.Interpreter = 'latex';
            comp.omega_textambLabel.Text = '$\omega_\text{amb}$';
            comp.psatTxt = uieditfield(comp, 'numeric');
            comp.psatTxt.AllowEmpty = 'on';
            comp.psatTxt.Editable = 'off';
            comp.psatTxt.Value = [];
            comp.omegaTxt = uieditfield(comp, 'numeric');
            comp.omegaTxt.AllowEmpty = 'on';
            comp.omegaTxt.Editable = 'off';
            comp.omegaTxt.Value = [];
            comp.phi0 = uieditfield(comp, 'numeric');
            comp.phi0.Limits = [0 1];
            comp.phi0.Value = 0.63;
            comp.Tain = uieditfield(comp, 'numeric');
            comp.Tain.Limits = [0 Inf];
            comp.Tain.Value = 295.7;
            comp.Twin = uieditfield(comp, 'numeric');
            comp.Twin.Limits = [0 Inf];
            comp.Twin.Value = 318.4;
            comp.Ttank = uieditfield(comp, 'numeric');
            comp.Ttank.Limits = [0 Inf];
            comp.Ttank.Value = 295.4;
            comp.Taout = uieditfield(comp, 'numeric');
            comp.Taout.Limits = [0 Inf];
            comp.Taout.Value = 296.7;
            comp.Twout = uieditfield(comp, 'numeric');
            comp.Twout.Limits = [0 Inf];
            comp.Twout.Value = 296.8;
            comp.phi1 = uieditfield(comp, 'numeric');
            comp.phi1.Limits = [0 1];
            comp.phi1.Value = 0.94;
            comp.deltaP = uieditfield(comp, 'numeric');
            comp.deltaP.Value = 82;
            comp.mwin = uieditfield(comp, 'numeric');
            comp.mwin.Limits = [0 Inf];
            comp.mwin.Value = 0.0114;
            comp.ma = uieditfield(comp, 'numeric');
            comp.ma.AllowEmpty = 'on';
            comp.ma.Editable = 'off';
            comp.ma.Value = [];
            comp.mwev = uieditfield(comp, 'numeric');
            comp.mwev.AllowEmpty = 'on';
            comp.mwev.Editable = 'off';
            comp.mwev.Value = [];
            comp.Tdew = uieditfield(comp, 'numeric');
            comp.Tdew.Limits = [0 Inf];
            comp.Tdew.AllowEmpty = 'on';
            comp.Tdew.Editable = 'off';
            comp.Tdew.Value = [];
            comp.Twet = uieditfield(comp, 'numeric');
            comp.Twet.Limits = [0 Inf];
            comp.Twet.AllowEmpty = 'on';
            comp.Twet.Editable = 'off';
            comp.Twet.Value = [];
            comp.p0 = uieditfield(comp, 'numeric');
            comp.p0.Limits = [0 Inf];
            comp.p0.ValueDisplayFormat = '%5.9g';
            comp.p0.Value = 94000;
            comp.psatLabel_2 = uilabel(comp);
            comp.psatLabel_2.Interpreter = 'latex';
            comp.psatLabel_2.Text = '$p_\text{amb}$';
            comp.UnidadesdetemperaturaSwitchLabel = uilabel(comp);
            comp.UnidadesdetemperaturaSwitchLabel.Text = 'Unidades de temperatura';
            comp.UnidadesdetemperaturaSwitch = uiswitch(comp, 'slider');
            comp.UnidadesdetemperaturaSwitch.Items = {'Kelvin', 'Celsius'};
            comp.UnidadesdetemperaturaSwitch.ItemsData = {'k', 'c'};
            comp.UnidadesdetemperaturaSwitch.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @UnidadesdetemperaturaSwitchValueChanged, true);
            comp.UnidadesdetemperaturaSwitch.Value = 'k';
            comp.deltaTa = uilabel(comp);
            comp.deltaTa.Interpreter = 'latex';
            comp.deltaTa.Text = '$\Delta T_a$';
            comp.deltaTw = uilabel(comp);
            comp.deltaTw.Interpreter = 'latex';
            comp.deltaTw.Text = '$\Delta T_w$';
            comp.deltaTwTxt = uieditfield(comp, 'numeric');
            comp.deltaTwTxt.AllowEmpty = 'on';
            comp.deltaTwTxt.Editable = 'off';
            comp.deltaTwTxt.Value = [];
            comp.deltaTaTxt = uieditfield(comp, 'numeric');
            comp.deltaTaTxt.AllowEmpty = 'on';
            comp.deltaTaTxt.Editable = 'off';
            comp.deltaTaTxt.Value = [];
            comp.omegaTxt_2 = uieditfield(comp, 'numeric');
            comp.omegaTxt_2.AllowEmpty = 'on';
            comp.omegaTxt_2.Editable = 'off';
            comp.omegaTxt_2.Value = [];
            comp.omega_textsalidaLabel = uilabel(comp);
            comp.omega_textsalidaLabel.Interpreter = 'latex';
            comp.omega_textsalidaLabel.Text = '$\omega_\text{salida}$';
            comp.psatTxt_2 = uieditfield(comp, 'numeric');
            comp.psatTxt_2.AllowEmpty = 'on';
            comp.psatTxt_2.Editable = 'off';
            comp.psatTxt_2.Value = [];
            comp.psatLabel_3 = uilabel(comp);
            comp.psatLabel_3.Interpreter = 'latex';
            comp.psatLabel_3.Text = '$p_\text{sat, salida}$';
            comp.deltaOmega = uieditfield(comp, 'numeric');
            comp.deltaOmega.AllowEmpty = 'on';
            comp.deltaOmega.Editable = 'off';
            comp.deltaOmega.Value = [];
            comp.deltaOmegaLabl = uilabel(comp);
            comp.deltaOmegaLabl.Interpreter = 'latex';
            comp.deltaOmegaLabl.Text = '$\Delta \omega$';
        end
    end
end